classdef TrimModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrimModuleUIFigure  matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        UITableComments     matlab.ui.control.Table
        Load                matlab.ui.control.Button
        Trim                matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        dataFilename
        dataFilepath
        dataFileExt
        
        commentFilename
        commentFilepath
        
        Comments
        CommentTimings
        
        RawData
    end
    
    methods (Access = private)
        
        function selectDataset(app)
            resetData(app)
            [file,path] = uigetfile('*.*');
            figure(app.TrimModuleUIFigure)
            if file==0
                return
            end
            [app.dataFilepath,app.dataFilename,app.dataFileExt] = fileparts(fullfile(path,file));
            if ~isempty(regexp(app.dataFileExt,'n(e|s)(v|\d)$','once'))
                d=uiprogressdlg(app.TrimModuleUIFigure,'Title','Loading Blackrock Data','Indeterminate','on');
                NS5 = openNSx(fullfile(app.dataFilepath,[app.dataFilename,'.ns5']),'t:1:2');
                tmp = {NS5.ElectrodesInfo.Label}';
                tmp = regexprep(tmp,'\W','');
                app.DropDown.Items = tmp;
                app.DropDown.ItemsData = 1:length(app.DropDown.Items);
                close(d)
                loadData(app)
                if exist(fullfile(app.dataFilepath,[app.dataFilename,'.nev']),'file')
                    loadComments(app);
                end
            elseif ~isempty(regexp(app.dataFileExt,'mat$','once'))
                %                 app.RawData = load(fullfile(path,file));
                %                 app.DropDown.Items = 1:size(data,1);
                %                 app.DrowpDown.ItemsData = 1:size(data,1);
                %                 app.ProcessedData = struct();
                %             app.ProcessedData(1).Label = 'Raw';
                %             app.ProcessedData(1).Data = app.RawData;
                %             app.ProcessedData(1).Steps = {};
                %             app.PlottedData = app.ProcessedData(1).Data;
                %             app.DatasetsDropDown.Items = {app.ProcessedData(1).Label};
                %             app.DatasetsDropDown.ItemsData = 1:length(app.DatasetsDropDown.Items);
                
            end
        end
        
        function loadData(app)
            d=uiprogressdlg(app.TrimModuleUIFigure,'Title',sprintf('Loading Signal: %s',app.DropDown.Items{app.DropDown.Value}),'Indeterminate','on');
            NS5 = openNSx(fullfile(app.dataFilepath,[app.dataFilename,'.ns5']),['c:' int2str(app.DropDown.Value)]);
            app.RawData = double(NS5.Data)*0.25;
            close(d)
            plot(app.UIAxes,app.RawData,'PickableParts','none')
            title(app.UIAxes, [app.DropDown.Items{app.DropDown.Value}])
            xlabel(app.UIAxes,'Time')
        end
        
        function loadComments(app)
            NEV = openNEV(fullfile(app.dataFilepath,[app.dataFilename,'.nev']),'nosave');
            app.Comments = string(NEV.Data.Comments.Text);
            app.Comments = regexprep(app.Comments,'\W*$','');
            app.CommentTimings = NEV.Data.Comments.TimeStamp;
            app.CommentTimings = app.CommentTimings(:);
            app.UITableComments.Data = table(app.Comments,app.CommentTimings);
        end
        
        function resetData(app)
            app.dataFilename = [];
            app.dataFilepath = [];
            
            app.commentFilename = [];
            app.commentFilepath = [];
            
            app.Comments = [];
            app.CommentTimings = [];
            
            app.RawData = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            selectDataset(app)
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            loadData(app)
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            selectDataset(app)
        end

        % Button pushed function: Trim
        function TrimButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrimModuleUIFigure = uifigure('Visible', 'off');
            app.TrimModuleUIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.TrimModuleUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITableComments = uitable(app.TrimModuleUIFigure);
            app.UITableComments.ColumnName = {'Comments'; 'Timestamps'};
            app.UITableComments.RowName = {};
            app.Load = uibutton(app.TrimModuleUIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load Data';
            app.Trim = uibutton(app.TrimModuleUIFigure, 'push');
            app.Trim.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.Trim.Text = 'Create Trimmed Dataset';
            app.DropDown = uidropdown(app.TrimModuleUIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            % Show the figure after all components are created
            app.TrimModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrimModule

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrimModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrimModuleUIFigure)
        end
    end
end